classdef SaLSa_SnippetAnnotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VideoSlider                   matlab.ui.control.Slider
        VideoSliderLabel              matlab.ui.control.Label
        currentsnippetEditField       matlab.ui.control.EditField
        currentsnippetEditFieldLabel  matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        BtypeListBox                  matlab.ui.control.ListBox
        BtypeListBoxLabel             matlab.ui.control.Label
        PlayReplayButton              matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Vinfo; % video info
        Btype; % 1, loc; 2, turn; 3, rear; 4, sniff; 5, groom; 6, pause; 7, other 
        Dpath;
        AviList;
        CurrAvi;
        cInfo;
        MyState;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % [~, path] = uigetfile('snippet*.avi');
            [~, path] = uigetfile('snippet*.mp4');
            app.Dpath = path;
            if isempty(app.AviList) == 1
                % app.AviList = dir(fullfile(path, 'snippet*.avi'));
                app.AviList = dir(fullfile(path, 'snippet*.mp4'));
                app.cInfo = cell(7,1); % cell array
            end

            app.CurrAvi = 1;
            app.MyState = true;
        end

        % Button pushed function: PlayReplayButton
        function PlayReplayButtonPushed(app, event)
            MySnippet = app.AviList(app.CurrAvi).name;
            app.currentsnippetEditField.Value = MySnippet;
            app.Vinfo = VideoReader(fullfile(app.Dpath, MySnippet));
            v = app.Vinfo;
            v.CurrentTime = 0;
            w = v.Width;
            h = v.Height;
            nFs = v.NumFrames;
            app.UIAxes.Position(3:4) = [w*2.3, h*2.3];
            fc = 1;
            while hasFrame(v) && app.MyState
              vidFrame = readFrame(v);
              image(vidFrame, 'Parent', app.UIAxes);
              app.UIAxes.Visible = 'off';
              app.VideoSlider.Value = fc/nFs*100;
              pause(1/v.FrameRate);
              fc = fc + 1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % extract snippet#
            MySnippet = app.currentsnippetEditField.Value;
            Pos1 = strfind(MySnippet, '_');
            Pos2 = strfind(MySnippet, '.');
            MyNum = str2double(MySnippet(Pos1+1:Pos2-1));

            % update cInfo
            value = app.BtypeListBox.Value;
            app.Btype = value;

            app.cInfo{value} = [app.cInfo{value}, MyNum];
            app.CurrAvi = app.CurrAvi + 1;

            % next snippet
            if app.CurrAvi <= length(app.AviList)
                MySnippet = app.AviList(app.CurrAvi).name;
                app.currentsnippetEditField.Value = MySnippet;
                app.Vinfo = VideoReader(fullfile(app.Dpath,MySnippet));
                v = app.Vinfo;
                w = v.Width;
                h = v.Height;
                nFs = v.NumFrames;
                app.UIAxes.Position(3:4) = [w*2.3, h*2.3];
                fc = 1;
                while hasFrame(v) && app.MyState
                  vidFrame = readFrame(v);
                  image(vidFrame, 'Parent', app.UIAxes);
                  app.UIAxes.Visible = 'off';
                  app.VideoSlider.Value = fc/nFs*100;
                  pause(1/v.FrameRate);
                  fc = fc + 1;
                end
            else
                cInfo = app.cInfo;
                save(fullfile(app.Dpath, 'cInfo.mat'), 'cInfo');
                
                % stop video
                app.MyState = false;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'snippet')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayReplayButton = uibutton(app.UIFigure, 'push');
            app.PlayReplayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayReplayButtonPushed, true);
            app.PlayReplayButton.Text = 'Play/Replay';
            app.BtypeListBoxLabel = uilabel(app.UIFigure);
            app.BtypeListBoxLabel.Text = 'Btype List Box';
            app.BtypeListBox = uilistbox(app.UIFigure);
            app.BtypeListBox.Items = {'Locomotion', 'Turn', 'Rearing', 'Sniffing', 'Grooming', 'Pause', 'Other'};
            app.BtypeListBox.ItemsData = [1 2 3 4 5 6 7];
            app.BtypeListBox.Value = 1;
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.currentsnippetEditFieldLabel = uilabel(app.UIFigure);
            app.currentsnippetEditFieldLabel.Text = 'current snippet';
            app.currentsnippetEditField = uieditfield(app.UIFigure, 'text');
            app.VideoSliderLabel = uilabel(app.UIFigure);
            app.VideoSliderLabel.Text = 'Video';
            app.VideoSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaLSa_SnippetAnnotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end